classdef email < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DLSUEMAILPERKSLabel  matlab.ui.control.Label
        Image                matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Image3               matlab.ui.control.Image
        Image4               matlab.ui.control.Image
        Image5               matlab.ui.control.Image
        Image6               matlab.ui.control.Image
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            web('https://drive.google.com/drive');
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            web('https://open.spotify.com/');
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            web('https://www.apple.com/ph/');
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            web('https://www.office.com/');
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            web('https://education.github.com/pack');
        end

        % Image clicked function: Image6
        function Image6Clicked(app, event)
            web('https://www.autodesk.com/education/edu-software/overview');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DLSUEMAILPERKSLabel = uilabel(app.UIFigure);
            app.DLSUEMAILPERKSLabel.Text = 'DLSU EMAIL PERKS';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'banner1.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'banner2.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = 'banner3.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'banner4.jpg';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = 'banner5.jpg';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @Image6Clicked, true);
            app.Image6.ImageSource = 'banner6.jpg';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel.Text = 'Unlimited Drive and Gmail Storage';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2.Text = '50% Discount on Spotify Premium';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3.Text = 'Discounts on the Apple Online Store';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4.Text = 'Unlimited OneDrive Storage';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5.Text = '$1000 worth of digital tools';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6.Text = 'Free AutoCad, Fusion360, etc.';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7.Text = 'Your DLSU email has a lot of perks, make sure to utilize and maximize them!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = email

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end