classdef flowcharts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        bitlyCOB121FlowchartsButton     matlab.ui.control.Button
        bitlyCOB120FlowchartsButton     matlab.ui.control.Button
        bitlyCOB119FlowchartsButton     matlab.ui.control.Button
        bitlyCOB118FlowchartsButton     matlab.ui.control.Button
        bitlyGCOE121FlowchartsButton    matlab.ui.control.Button
        httpstinyurlcomGCOE120FlowchartButton  matlab.ui.control.Button
        httpstinyurlcomGCOE119FlowchartButton  matlab.ui.control.Button
        httpstinyurlcomGCOE118FlowchartButton  matlab.ui.control.Button
        httpsbitlyCLA121FlowchartsButton  matlab.ui.control.Button
        httpstinyurlcomCLAflowchartsButton  matlab.ui.control.Button
        bitlyCOS121FlowchartsAY2122Button  matlab.ui.control.Button
        bitlyCOSFlowchartsAY2223Button  matlab.ui.control.Button
        dlsusegcomflowchartsButton      matlab.ui.control.Button
        oneccsmlstudentservicesButton   matlab.ui.control.Button
        bitlyBAGCEDRevisedFlowchartsButton  matlab.ui.control.Button
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_20  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_19  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_18  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_17  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_16  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_15  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_14  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_13  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_12  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_11  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_10  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_9  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_8  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel  matlab.ui.control.Label
        FLOWCHARTSLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function HTMLDataChanged(app, event)
            
        end

        % Button pushed function: bitlyBAGCEDRevisedFlowchartsButton
        function bitlyBAGCEDRevisedFlowchartsButtonPushed(app, event)
            web('bit.ly/BAGCEDRevisedFlowcharts');
        end

        % Button pushed function: oneccsmlstudentservicesButton
        function oneccsmlstudentservicesButtonPushed(app, event)
            web('oneccs.ml/student-services');
        end

        % Button pushed function: dlsusegcomflowchartsButton
        function dlsusegcomflowchartsButtonPushed(app, event)
             web('dlsu-seg.com/flowcharts');
        end

        % Button pushed function: bitlyCOSFlowchartsAY2223Button
        function bitlyCOSFlowchartsAY2223ButtonPushed(app, event)
            web('bit.ly/COSFlowcharts-AY2223');
        end

        % Button pushed function: bitlyCOS121FlowchartsAY2122Button
        function bitlyCOS121FlowchartsAY2122ButtonPushed(app, event)
            web('bit.ly/COS121Flowcharts-AY2122');
        end

        % Button pushed function: httpstinyurlcomCLAflowchartsButton
        function httpstinyurlcomCLAflowchartsButtonPushed(app, event)
            web('https://tinyurl.com/CLAflowcharts');
        end

        % Button pushed function: httpsbitlyCLA121FlowchartsButton
        function httpsbitlyCLA121FlowchartsButtonPushed(app, event)
            web('https://bit.ly/CLA121-Flowcharts');
        end

        % Button pushed function: httpstinyurlcomGCOE118FlowchartButton
        function httpstinyurlcomGCOE118FlowchartButtonPushed(app, event)
            web('https://tinyurl.com/GCOE118Flowchart');
        end

        % Button pushed function: httpstinyurlcomGCOE119FlowchartButton
        function httpstinyurlcomGCOE119FlowchartButtonPushed(app, event)
            web('https://tinyurl.com/GCOE119Flowchart');
        end

        % Button pushed function: httpstinyurlcomGCOE120FlowchartButton
        function httpstinyurlcomGCOE120FlowchartButtonPushed(app, event)
            web('https://tinyurl.com/GCOE120Flowchart');
        end

        % Button pushed function: bitlyGCOE121FlowchartsButton
        function bitlyGCOE121FlowchartsButtonPushed(app, event)
            web('https://bit.ly/GCOE121Flowcharts');
        end

        % Button pushed function: bitlyCOB118FlowchartsButton
        function bitlyCOB118FlowchartsButtonPushed(app, event)
            web('bit.ly/COB118Flowcharts');
        end

        % Button pushed function: bitlyCOB119FlowchartsButton
        function bitlyCOB119FlowchartsButtonPushed(app, event)
             web('bit.ly/COB119Flowcharts');
        end

        % Button pushed function: bitlyCOB120FlowchartsButton
        function bitlyCOB120FlowchartsButtonPushed(app, event)
             web('bit.ly/COB120Flowcharts');
        end

        % Button pushed function: bitlyCOB121FlowchartsButton
        function bitlyCOB121FlowchartsButtonPushed(app, event)
             web('bit.ly/COB121Flowcharts');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FLOWCHARTSLabel = uilabel(app.UIFigure);
            app.FLOWCHARTSLabel.Text = 'FLOWCHARTS';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel.Text = 'BAGCED';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2.Text = 'CCS';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3.Text = 'SOE';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4.Text = 'COS';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5.Text = '118-120';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6.Text = '121';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7.Text = 'CLA';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_8 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_8.Text = '118-120';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_9 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_9.Text = '121';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_10 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_10.Text = 'GCOE';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_11 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_11.Text = '118';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_12 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_12.Text = '119';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_13 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_13.Text = '120';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_14 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_14.Text = '121';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_15 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_15.Text = 'RVRCOB';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_16 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_16.Text = '118';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_17 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_17.Text = '119';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_18 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_18.Text = '120';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_19 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_19.Text = '121';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_20 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_20.Text = {'Make sure you are using your DLSU Email when accessing'; 'the links!'};
            app.bitlyBAGCEDRevisedFlowchartsButton = uibutton(app.UIFigure, 'push');
            app.bitlyBAGCEDRevisedFlowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @bitlyBAGCEDRevisedFlowchartsButtonPushed, true);
            app.bitlyBAGCEDRevisedFlowchartsButton.Text = 'bit.ly/BAGCEDRevisedFlowcharts';
            app.oneccsmlstudentservicesButton = uibutton(app.UIFigure, 'push');
            app.oneccsmlstudentservicesButton.ButtonPushedFcn = createCallbackFcn(app, @oneccsmlstudentservicesButtonPushed, true);
            app.oneccsmlstudentservicesButton.Text = 'oneccs.ml/student-services';
            app.dlsusegcomflowchartsButton = uibutton(app.UIFigure, 'push');
            app.dlsusegcomflowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @dlsusegcomflowchartsButtonPushed, true);
            app.dlsusegcomflowchartsButton.Text = 'dlsu-seg.com/flowcharts';
            app.bitlyCOSFlowchartsAY2223Button = uibutton(app.UIFigure, 'push');
            app.bitlyCOSFlowchartsAY2223Button.ButtonPushedFcn = createCallbackFcn(app, @bitlyCOSFlowchartsAY2223ButtonPushed, true);
            app.bitlyCOSFlowchartsAY2223Button.Text = 'bit.ly/COSFlowcharts-AY2223';
            app.bitlyCOS121FlowchartsAY2122Button = uibutton(app.UIFigure, 'push');
            app.bitlyCOS121FlowchartsAY2122Button.ButtonPushedFcn = createCallbackFcn(app, @bitlyCOS121FlowchartsAY2122ButtonPushed, true);
            app.bitlyCOS121FlowchartsAY2122Button.Text = 'bit.ly/COS121Flowcharts-AY2122';
            app.httpstinyurlcomCLAflowchartsButton = uibutton(app.UIFigure, 'push');
            app.httpstinyurlcomCLAflowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @httpstinyurlcomCLAflowchartsButtonPushed, true);
            app.httpstinyurlcomCLAflowchartsButton.Text = 'https://tinyurl.com/CLAflowcharts';
            app.httpsbitlyCLA121FlowchartsButton = uibutton(app.UIFigure, 'push');
            app.httpsbitlyCLA121FlowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @httpsbitlyCLA121FlowchartsButtonPushed, true);
            app.httpsbitlyCLA121FlowchartsButton.Text = 'https://bit.ly/CLA121-Flowcharts';
            app.httpstinyurlcomGCOE118FlowchartButton = uibutton(app.UIFigure, 'push');
            app.httpstinyurlcomGCOE118FlowchartButton.ButtonPushedFcn = createCallbackFcn(app, @httpstinyurlcomGCOE118FlowchartButtonPushed, true);
            app.httpstinyurlcomGCOE118FlowchartButton.Text = 'https://tinyurl.com/GCOE118Flowchart';
            app.httpstinyurlcomGCOE119FlowchartButton = uibutton(app.UIFigure, 'push');
            app.httpstinyurlcomGCOE119FlowchartButton.ButtonPushedFcn = createCallbackFcn(app, @httpstinyurlcomGCOE119FlowchartButtonPushed, true);
            app.httpstinyurlcomGCOE119FlowchartButton.Text = 'https://tinyurl.com/GCOE119Flowchart';
            app.httpstinyurlcomGCOE120FlowchartButton = uibutton(app.UIFigure, 'push');
            app.httpstinyurlcomGCOE120FlowchartButton.ButtonPushedFcn = createCallbackFcn(app, @httpstinyurlcomGCOE120FlowchartButtonPushed, true);
            app.httpstinyurlcomGCOE120FlowchartButton.Text = 'https://tinyurl.com/GCOE120Flowchart';
            app.bitlyGCOE121FlowchartsButton = uibutton(app.UIFigure, 'push');
            app.bitlyGCOE121FlowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @bitlyGCOE121FlowchartsButtonPushed, true);
            app.bitlyGCOE121FlowchartsButton.Text = 'bit.ly/GCOE121Flowcharts';
            app.bitlyCOB118FlowchartsButton = uibutton(app.UIFigure, 'push');
            app.bitlyCOB118FlowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @bitlyCOB118FlowchartsButtonPushed, true);
            app.bitlyCOB118FlowchartsButton.Text = 'bit.ly/COB118Flowcharts';
            app.bitlyCOB119FlowchartsButton = uibutton(app.UIFigure, 'push');
            app.bitlyCOB119FlowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @bitlyCOB119FlowchartsButtonPushed, true);
            app.bitlyCOB119FlowchartsButton.Text = 'bit.ly/COB119Flowcharts';
            app.bitlyCOB120FlowchartsButton = uibutton(app.UIFigure, 'push');
            app.bitlyCOB120FlowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @bitlyCOB120FlowchartsButtonPushed, true);
            app.bitlyCOB120FlowchartsButton.Text = 'bit.ly/COB120Flowcharts';
            app.bitlyCOB121FlowchartsButton = uibutton(app.UIFigure, 'push');
            app.bitlyCOB121FlowchartsButton.ButtonPushedFcn = createCallbackFcn(app, @bitlyCOB121FlowchartsButtonPushed, true);
            app.bitlyCOB121FlowchartsButton.Text = 'bit.ly/COB121Flowcharts';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flowcharts

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end