classdef mobile_apps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MOBILEAPPLICATIONSLabel  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel  matlab.ui.control.Label
        Image2                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7  matlab.ui.control.Label
        Image2_2                 matlab.ui.control.Image
        Image3_2                 matlab.ui.control.Image
        Image4_2                 matlab.ui.control.Image
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_8  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_9  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_10  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_11  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_12  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_13  matlab.ui.control.Label
        Image3_3                 matlab.ui.control.Image
        Image4_3                 matlab.ui.control.Image
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_14  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_15  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_16  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_17  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_18  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_19  matlab.ui.control.Label
        Image3_4                 matlab.ui.control.Image
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_20  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            web('https://dlsu.instructure.com/');
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
             web('https://drive.google.com/drive');
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            web('https://telegram.org/');
        end

        % Image clicked function: Image2_2
        function Image2_2Clicked(app, event)
            web('https://classup.plokia.com/');
        end

        % Image clicked function: Image3_2
        function Image3_2Clicked(app, event)
            web('https://www.chegg.com/');
        end

        % Image clicked function: Image4_2
        function Image4_2Clicked(app, event)
            web('https://www.khanacademy.org/');
        end

        % Image clicked function: Image3_4
        function Image3_4Clicked(app, event)
            web('https://kahoot.com/');
        end

        % Image clicked function: Image3_3
        function Image3_3Clicked(app, event)
            web('https://quizlet.com/');
        end

        % Image clicked function: Image4_3
        function Image4_3Clicked(app, event)
            web('https://www.symbolab.com/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MOBILEAPPLICATIONSLabel = uilabel(app.UIFigure);
            app.MOBILEAPPLICATIONSLabel.Text = 'MOBILE APPLICATIONS';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel.Text = {'Here are some of the mobile applications and softwares that DLSU community '; 'uses to make their DLSU life a little bit easier.'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'canvas-logo.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = 'gdrive.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = 'telegram-logo-png.png';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2.Text = 'Canvas';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3.Text = 'Google Drive';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4.Text = 'Telegram';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_5.Text = {'Canvas or Animo Space, is'; 'the official Learning Management'; 'System of DLSU.'};
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_6.Text = {'Cloud storage, where you can '; 'store all your academic files and'; 'more. Also allows access to '; 'Google Docs, Sheets, and Slides.'};
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_7.Text = {'A messaging application like '; 'Messenger but more useful if you '; 'want to see the links and files'; 'you sent.'};
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @Image2_2Clicked, true);
            app.Image2_2.ImageSource = 'class up.png';
            app.Image3_2 = uiimage(app.UIFigure);
            app.Image3_2.ImageClickedFcn = createCallbackFcn(app, @Image3_2Clicked, true);
            app.Image3_2.ImageSource = 'chegg-logo-freelogovectors.net_.png';
            app.Image4_2 = uiimage(app.UIFigure);
            app.Image4_2.ImageClickedFcn = createCallbackFcn(app, @Image4_2Clicked, true);
            app.Image4_2.ImageSource = 'khan-logo.png';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_8 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_8.Text = 'ClassUp';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_9 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_9.Text = 'Chegg';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_10 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_10.Text = 'Khan Academy';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_11 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_11.Text = {'ClassUp is an app where you'; 'can manage your class '; 'schedules and track tasks.'};
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_12 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_12.Text = {'Chegg provides digital and '; 'physical textbook rentals, '; 'online tutoring,'; 'and other student services.'};
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_13 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_13.Text = {'Khan Academy offers free'; 'online lessons on various'; 'fields and subjects.'};
            app.Image3_3 = uiimage(app.UIFigure);
            app.Image3_3.ImageClickedFcn = createCallbackFcn(app, @Image3_3Clicked, true);
            app.Image3_3.ImageSource = 'quizlet.png';
            app.Image4_3 = uiimage(app.UIFigure);
            app.Image4_3.ImageClickedFcn = createCallbackFcn(app, @Image4_3Clicked, true);
            app.Image4_3.ImageSource = 'symbolab.png';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_14 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_14.Text = 'Kahoot!';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_15 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_15.Text = 'Quizlet';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_16 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_16.Text = 'Symbolab';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_17 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_17.Text = {'Kahoot allows for fun and'; 'interactive multiple choice'; 'activities.'};
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_18 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_18.Text = {'Quizlet allows it users to'; 'create flashcards.'};
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_19 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_19.Text = {'Symbolab is an online'; 'math solving engine.'; 'It also offers step-by-step'; 'solutions.'};
            app.Image3_4 = uiimage(app.UIFigure);
            app.Image3_4.ImageClickedFcn = createCallbackFcn(app, @Image3_4Clicked, true);
            app.Image3_4.ImageSource = 'kahoot-logo.png';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_20 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_20.Text = {'These are only some of the softwares that the DLSU community'; 'uses, let us know if you use any other applications!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobile_apps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end