classdef slang < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AddaLaSallianSlangEditFieldLabel  matlab.ui.control.Label
        AddaLaSallianSlangEditField     matlab.ui.control.EditField
        WhatisitsMeaningEditFieldLabel  matlab.ui.control.Label
        WhatisitsMeaningEditField       matlab.ui.control.EditField
        SlangField                      matlab.ui.control.TextArea
        MeaningField                    matlab.ui.control.TextArea
        LASALLIANSLANGLabel             matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3  matlab.ui.control.Label
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AddaLaSallianSlangEditField
        function AddaLaSallianSlangEditFieldValueChanged(app, event)
            slang_value = app.AddaLaSallianSlangEditField.Value;
            fid = fopen('slang.txt', 'a+');
            fprintf(fid, '%s\n', slang_value);
            temp = fileread('slang.txt');
            app.SlangField.Value = temp;
        end

        % Value changing function: AddaLaSallianSlangEditField
        function AddaLaSallianSlangEditFieldValueChanging(app, event)
            
        end

        % Value changed function: WhatisitsMeaningEditField
        function WhatisitsMeaningEditFieldValueChanged(app, event)
            meaning_value = app.WhatisitsMeaningEditField.Value;
            fid2 = fopen('meaning.txt', 'a+');
            fprintf(fid2, '%s\n', meaning_value);
            temp2 = fileread('meaning.txt');
            app.MeaningField.Value = temp2;
        end

        % Callback function
        function SlangWindowValueChanged(app, event)
            value = app.SlangWindow.Value;
        end

        % Value changed function: SlangField
        function SlangFieldValueChanged(app, event)
            value = app.SlangField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddaLaSallianSlangEditFieldLabel = uilabel(app.UIFigure);
            app.AddaLaSallianSlangEditFieldLabel.Text = 'Add a La Sallian Slang';
            app.AddaLaSallianSlangEditField = uieditfield(app.UIFigure, 'text');
            app.AddaLaSallianSlangEditField.ValueChangedFcn = createCallbackFcn(app, @AddaLaSallianSlangEditFieldValueChanged, true);
            app.AddaLaSallianSlangEditField.ValueChangingFcn = createCallbackFcn(app, @AddaLaSallianSlangEditFieldValueChanging, true);
            app.WhatisitsMeaningEditFieldLabel = uilabel(app.UIFigure);
            app.WhatisitsMeaningEditFieldLabel.Text = 'What is it''s Meaning';
            app.WhatisitsMeaningEditField = uieditfield(app.UIFigure, 'text');
            app.WhatisitsMeaningEditField.ValueChangedFcn = createCallbackFcn(app, @WhatisitsMeaningEditFieldValueChanged, true);
            app.SlangField = uitextarea(app.UIFigure);
            app.SlangField.ValueChangedFcn = createCallbackFcn(app, @SlangFieldValueChanged, true);
            app.SlangField.Editable = 'off';
            app.MeaningField = uitextarea(app.UIFigure);
            app.MeaningField.Editable = 'off';
            app.LASALLIANSLANGLabel = uilabel(app.UIFigure);
            app.LASALLIANSLANGLabel.Text = 'LA SALLIAN SLANG';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel.Text = 'Here are a few terms/abbreviations that the La Sallian Community uses!';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_2.Text = {'CADS'; 'Goks'};
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_3.Text = {'Cory Aquino Democratic Space'; 'Gokongwei Hall'};
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4 = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel_4.Text = 'Add new La Sallian Slang and it''s meaning into our database !';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slang

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end