classdef study < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel  matlab.ui.control.Label
        STUDYAREASLabel     matlab.ui.control.Label
        Room10Switch        matlab.ui.control.Switch
        Room10SwitchLabel   matlab.ui.control.Label
        Room7Switch         matlab.ui.control.Switch
        Room7SwitchLabel    matlab.ui.control.Label
        Room8Switch         matlab.ui.control.Switch
        Room8SwitchLabel    matlab.ui.control.Label
        Room5Switch_3       matlab.ui.control.Switch
        Room5Switch_3Label  matlab.ui.control.Label
        Room6Switch         matlab.ui.control.Switch
        Room6SwitchLabel    matlab.ui.control.Label
        Room9Switch         matlab.ui.control.Switch
        Room9SwitchLabel    matlab.ui.control.Label
        Room3Switch         matlab.ui.control.Switch
        Room3SwitchLabel    matlab.ui.control.Label
        Room4Switch         matlab.ui.control.Switch
        Room4SwitchLabel    matlab.ui.control.Label
        Room1Switch         matlab.ui.control.Switch
        Room1SwitchLabel    matlab.ui.control.Label
        Room2Switch         matlab.ui.control.Switch
        Room2Label          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Room1SwitchValueChanged(app, event)
            value = app.Room1Switch.Value;
            answer = questdlg('Reserve Room 1?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer
                case 'Yes'
                    reserve1 = 1;
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
                    reserve1 = 0;
            end
        end

        % Value changed function: Room2Switch
        function Room2SwitchValueChanged(app, event)
            value = app.Room2Switch.Value;
            answer2 = questdlg('Reserve Room 2?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer2
                case 'Yes'
                    reserve2deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room1Switch
        function Room1SwitchValueChanged2(app, event)
            value = app.Room1Switch.Value;
            answer1 = questdlg('Reserve Room 1?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer1
                case 'Yes'
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room3Switch
        function Room3SwitchValueChanged(app, event)
            value = app.Room3Switch.Value;
            answer3 = questdlg('Reserve Room 3?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer3
                case 'Yes'
                    reserve3deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room4Switch
        function Room4SwitchValueChanged(app, event)
            value = app.Room4Switch.Value;
            answer4 = questdlg('Reserve Room 4?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer4
                case 'Yes'
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room5Switch_3
        function Room5Switch_3ValueChanged(app, event)
            value = app.Room5Switch_3.Value;
            answer5 = questdlg('Reserve Room 5?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer5
                case 'Yes'
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room6Switch
        function Room6SwitchValueChanged(app, event)
            value = app.Room6Switch.Value;
            answer6 = questdlg('Reserve Room 6?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer6
                case 'Yes'
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room7Switch
        function Room7SwitchValueChanged(app, event)
            value = app.Room7Switch.Value;
            answer7 = questdlg('Reserve Room 7?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer7
                case 'Yes'
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room8Switch
        function Room8SwitchValueChanged(app, event)
            value = app.Room8Switch.Value;
            answer8 = questdlg('Reserve Room 8?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer8
                case 'Yes'
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room9Switch
        function Room9SwitchValueChanged(app, event)
            value = app.Room9Switch.Value;
            answer9 = questdlg('Reserve Room 9?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer9
                case 'Yes'
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end

        % Value changed function: Room10Switch
        function Room10SwitchValueChanged(app, event)
            value = app.Room10Switch.Value;
            answer10 = questdlg('Reserve Room 10?', ...
                'Reservation', ...
                'Yes','No','No');
            switch answer10
                case 'Yes'
                    reserve1deets = inputdlg({'Name','ID Number'},...
                        'Customer', [1 50; 1 20]);
                case 'No'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Room2Label = uilabel(app.UIFigure);
            app.Room2Label.Text = 'Room 2';
            app.Room2Switch = uiswitch(app.UIFigure, 'slider');
            app.Room2Switch.Items = {'Available', 'Reserved'};
            app.Room2Switch.ValueChangedFcn = createCallbackFcn(app, @Room2SwitchValueChanged, true);
            app.Room2Switch.Value = 'Available';
            app.Room1SwitchLabel = uilabel(app.UIFigure);
            app.Room1SwitchLabel.Text = 'Room 1';
            app.Room1Switch = uiswitch(app.UIFigure, 'slider');
            app.Room1Switch.Items = {'Available', 'Reserved'};
            app.Room1Switch.ValueChangedFcn = createCallbackFcn(app, @Room1SwitchValueChanged2, true);
            app.Room1Switch.Value = 'Available';
            app.Room4SwitchLabel = uilabel(app.UIFigure);
            app.Room4SwitchLabel.Text = 'Room 4';
            app.Room4Switch = uiswitch(app.UIFigure, 'slider');
            app.Room4Switch.Items = {'Available', 'Reserved'};
            app.Room4Switch.ValueChangedFcn = createCallbackFcn(app, @Room4SwitchValueChanged, true);
            app.Room4Switch.Value = 'Available';
            app.Room3SwitchLabel = uilabel(app.UIFigure);
            app.Room3SwitchLabel.Text = 'Room 3';
            app.Room3Switch = uiswitch(app.UIFigure, 'slider');
            app.Room3Switch.Items = {'Available', 'Reserved'};
            app.Room3Switch.ValueChangedFcn = createCallbackFcn(app, @Room3SwitchValueChanged, true);
            app.Room3Switch.Value = 'Available';
            app.Room9SwitchLabel = uilabel(app.UIFigure);
            app.Room9SwitchLabel.Text = 'Room 9';
            app.Room9Switch = uiswitch(app.UIFigure, 'slider');
            app.Room9Switch.Items = {'Available', 'Reserved'};
            app.Room9Switch.ValueChangedFcn = createCallbackFcn(app, @Room9SwitchValueChanged, true);
            app.Room9Switch.Value = 'Available';
            app.Room6SwitchLabel = uilabel(app.UIFigure);
            app.Room6SwitchLabel.Text = 'Room 6';
            app.Room6Switch = uiswitch(app.UIFigure, 'slider');
            app.Room6Switch.Items = {'Available', 'Reserved'};
            app.Room6Switch.ValueChangedFcn = createCallbackFcn(app, @Room6SwitchValueChanged, true);
            app.Room6Switch.Value = 'Available';
            app.Room5Switch_3Label = uilabel(app.UIFigure);
            app.Room5Switch_3Label.Text = 'Room 5';
            app.Room5Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Room5Switch_3.Items = {'Available', 'Reserved'};
            app.Room5Switch_3.ValueChangedFcn = createCallbackFcn(app, @Room5Switch_3ValueChanged, true);
            app.Room5Switch_3.Value = 'Available';
            app.Room8SwitchLabel = uilabel(app.UIFigure);
            app.Room8SwitchLabel.Text = 'Room 8';
            app.Room8Switch = uiswitch(app.UIFigure, 'slider');
            app.Room8Switch.Items = {'Available', 'Reserved'};
            app.Room8Switch.ValueChangedFcn = createCallbackFcn(app, @Room8SwitchValueChanged, true);
            app.Room8Switch.Value = 'Available';
            app.Room7SwitchLabel = uilabel(app.UIFigure);
            app.Room7SwitchLabel.Text = 'Room 7';
            app.Room7Switch = uiswitch(app.UIFigure, 'slider');
            app.Room7Switch.Items = {'Available', 'Reserved'};
            app.Room7Switch.ValueChangedFcn = createCallbackFcn(app, @Room7SwitchValueChanged, true);
            app.Room7Switch.Value = 'Available';
            app.Room10SwitchLabel = uilabel(app.UIFigure);
            app.Room10SwitchLabel.Text = 'Room 10';
            app.Room10Switch = uiswitch(app.UIFigure, 'slider');
            app.Room10Switch.Items = {'Available', 'Reserved'};
            app.Room10Switch.ValueChangedFcn = createCallbackFcn(app, @Room10SwitchValueChanged, true);
            app.Room10Switch.Value = 'Available';
            app.STUDYAREASLabel = uilabel(app.UIFigure);
            app.STUDYAREASLabel.Text = 'STUDY AREAS';
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel = uilabel(app.UIFigure);
            app.Gate1SouthGateGate2HenrySyGateGate3VelascoGateLabel.Text = 'Reserve a Discussion Room in Henry Sy Sr. Hall now!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = study

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end